classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        PixelSizemmEditField           matlab.ui.control.NumericEditField
        PixelSizemmEditFieldLabel      matlab.ui.control.Label
        BaselinemmEditField            matlab.ui.control.NumericEditField
        BaselinemmEditFieldLabel       matlab.ui.control.Label
        FocalLengthmmEditField         matlab.ui.control.NumericEditField
        FocalLengthmmEditFieldLabel    matlab.ui.control.Label
        ShiftEditField                 matlab.ui.control.NumericEditField
        ShiftEditFieldLabel            matlab.ui.control.Label
        yNumPixelsEditField            matlab.ui.control.NumericEditField
        yNumPixelsEditFieldLabel       matlab.ui.control.Label
        xNumPixelsEditField            matlab.ui.control.NumericEditField
        xNumPixelsEditFieldLabel       matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        CameraXTab                     matlab.ui.container.Tab
        CameraXCentroidRightEditField  matlab.ui.control.NumericEditField
        CameraXCentroidRightEditFieldLabel  matlab.ui.control.Label
        CameraXCentroidLeftEditField   matlab.ui.control.NumericEditField
        CameraXCentroidLeftEditFieldLabel  matlab.ui.control.Label
        RightEditField                 matlab.ui.control.NumericEditField
        RightEditFieldLabel            matlab.ui.control.Label
        LeftEditField                  matlab.ui.control.NumericEditField
        LeftEditFieldLabel             matlab.ui.control.Label
        CameraYTab                     matlab.ui.container.Tab
        CameraYCentroidRightEditField  matlab.ui.control.NumericEditField
        CameraYCentroidLabel           matlab.ui.control.Label
        CameraYCentroidLeftEditField   matlab.ui.control.NumericEditField
        CameraYCentroidLeftEditFieldLabel  matlab.ui.control.Label
        RightEditField_2               matlab.ui.control.NumericEditField
        RightEditField_2Label          matlab.ui.control.Label
        LeftEditField_2                matlab.ui.control.NumericEditField
        LeftEditField_2Label           matlab.ui.control.Label
        DisparityDepthTab              matlab.ui.container.Tab
        YpositionmmEditField           matlab.ui.control.NumericEditField
        YpositionmmLabel               matlab.ui.control.Label
        XpositionmmEditField           matlab.ui.control.NumericEditField
        XpositionmmEditFieldLabel      matlab.ui.control.Label
        depthmmEditField               matlab.ui.control.NumericEditField
        depthmmEditFieldLabel          matlab.ui.control.Label
        disparitymmEditField           matlab.ui.control.NumericEditField
        disparitymmEditFieldLabel      matlab.ui.control.Label
        CalculateButton                matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
               % cxLeft
               app.LeftEditField.Value = (app.xNumPixelsEditField.Value)/2
               % cxRight
               app.RightEditField.Value = (app.xNumPixelsEditField.Value)/2 
               % xLeft
               app.CameraXCentroidLeftEditField.Value = (app.LeftEditField.Value + app.ShiftEditField.Value)
               % xRight
               app.CameraXCentroidRightEditField.Value = (app.RightEditField.Value - app.ShiftEditField.Value)
               % cyLeft
               app.LeftEditField_2.Value = (app.yNumPixelsEditField.Value)/2
               % cyRight
               app.RightEditField_2.Value = (app.yNumPixelsEditField.Value)/2 
               % yLeft
               app.CameraYCentroidLeftEditField.Value = (app.LeftEditField_2.Value + app.ShiftEditField.Value)
               % yRight
               app.CameraYCentroidRightEditField.Value = (app.RightEditField_2.Value + app.ShiftEditField.Value)
               % disparity in millimeters
               app.disparitymmEditField.Value = (abs((app.CameraXCentroidLeftEditField.Value-app.LeftEditField.Value)-(app.CameraXCentroidRightEditField.Value-app.RightEditField.Value))*app.PixelSizemmEditField.Value)   
               % depth in millimeters
               app.depthmmEditField.Value = (app.BaselinemmEditField.Value*app.FocalLengthmmEditField.Value)/app.disparitymmEditField.Value                                    
               % X position in millimeters
               app.XpositionmmEditField.Value = (app.depthmmEditField.Value*(app.CameraXCentroidLeftEditField.Value-app.LeftEditField.Value)*app.PixelSizemmEditField.Value)/app.FocalLengthmmEditField.Value                   
               % Y position in millimeters
               app.YpositionmmEditField.Value = (app.depthmmEditField.Value*(app.CameraYCentroidLeftEditField.Value-app.LeftEditField_2.Value)*app.PixelSizemmEditField.Value)/app.FocalLengthmmEditField.Value                   
                    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CameraXTab = uitab(app.TabGroup);
            app.CameraXTab.Title = 'Camera X ';
            app.LeftEditFieldLabel = uilabel(app.CameraXTab);
            app.LeftEditFieldLabel.Text = 'Left';
            app.LeftEditField = uieditfield(app.CameraXTab, 'numeric');
            app.RightEditFieldLabel = uilabel(app.CameraXTab);
            app.RightEditFieldLabel.Text = 'Right';
            app.RightEditField = uieditfield(app.CameraXTab, 'numeric');
            app.CameraXCentroidLeftEditFieldLabel = uilabel(app.CameraXTab);
            app.CameraXCentroidLeftEditFieldLabel.Text = 'Camera X Centroid Left';
            app.CameraXCentroidLeftEditField = uieditfield(app.CameraXTab, 'numeric');
            app.CameraXCentroidRightEditFieldLabel = uilabel(app.CameraXTab);
            app.CameraXCentroidRightEditFieldLabel.Text = 'Camera X Centroid Right';
            app.CameraXCentroidRightEditField = uieditfield(app.CameraXTab, 'numeric');
            app.CameraYTab = uitab(app.TabGroup);
            app.CameraYTab.Title = 'Camera Y';
            app.LeftEditField_2Label = uilabel(app.CameraYTab);
            app.LeftEditField_2Label.Text = 'Left';
            app.LeftEditField_2 = uieditfield(app.CameraYTab, 'numeric');
            app.RightEditField_2Label = uilabel(app.CameraYTab);
            app.RightEditField_2Label.Text = 'Right';
            app.RightEditField_2 = uieditfield(app.CameraYTab, 'numeric');
            app.CameraYCentroidLeftEditFieldLabel = uilabel(app.CameraYTab);
            app.CameraYCentroidLeftEditFieldLabel.Text = 'Camera Y Centroid Left';
            app.CameraYCentroidLeftEditField = uieditfield(app.CameraYTab, 'numeric');
            app.CameraYCentroidLabel = uilabel(app.CameraYTab);
            app.CameraYCentroidLabel.Text = 'Camera Y Centroid Right';
            app.CameraYCentroidRightEditField = uieditfield(app.CameraYTab, 'numeric');
            app.DisparityDepthTab = uitab(app.TabGroup);
            app.DisparityDepthTab.Title = 'Disparity/Depth';
            app.disparitymmEditFieldLabel = uilabel(app.DisparityDepthTab);
            app.disparitymmEditFieldLabel.Text = 'disparity (mm)';
            app.disparitymmEditField = uieditfield(app.DisparityDepthTab, 'numeric');
            app.depthmmEditFieldLabel = uilabel(app.DisparityDepthTab);
            app.depthmmEditFieldLabel.Text = 'depth (mm)';
            app.depthmmEditField = uieditfield(app.DisparityDepthTab, 'numeric');
            app.XpositionmmEditFieldLabel = uilabel(app.DisparityDepthTab);
            app.XpositionmmEditFieldLabel.Text = 'X position (mm)';
            app.XpositionmmEditField = uieditfield(app.DisparityDepthTab, 'numeric');
            app.YpositionmmLabel = uilabel(app.DisparityDepthTab);
            app.YpositionmmLabel.Text = 'Y position (mm)';
            app.YpositionmmEditField = uieditfield(app.DisparityDepthTab, 'numeric');
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.xNumPixelsEditFieldLabel = uilabel(app.Panel);
            app.xNumPixelsEditFieldLabel.Text = 'xNumPixels';
            app.xNumPixelsEditField = uieditfield(app.Panel, 'numeric');
            app.xNumPixelsEditField.Value = 752;
            app.yNumPixelsEditFieldLabel = uilabel(app.Panel);
            app.yNumPixelsEditFieldLabel.Text = 'yNumPixels';
            app.yNumPixelsEditField = uieditfield(app.Panel, 'numeric');
            app.yNumPixelsEditField.Value = 480;
            app.ShiftEditFieldLabel = uilabel(app.Panel);
            app.ShiftEditFieldLabel.Text = 'Shift';
            app.ShiftEditField = uieditfield(app.Panel, 'numeric');
            app.ShiftEditField.Value = 10;
            app.FocalLengthmmEditFieldLabel = uilabel(app.Panel);
            app.FocalLengthmmEditFieldLabel.Text = 'Focal Length (mm)';
            app.FocalLengthmmEditField = uieditfield(app.Panel, 'numeric');
            app.FocalLengthmmEditField.Value = 6;
            app.BaselinemmEditFieldLabel = uilabel(app.Panel);
            app.BaselinemmEditFieldLabel.Text = 'Baseline (mm)';
            app.BaselinemmEditField = uieditfield(app.Panel, 'numeric');
            app.BaselinemmEditField.Value = 60;
            app.PixelSizemmEditFieldLabel = uilabel(app.Panel);
            app.PixelSizemmEditFieldLabel.Text = 'Pixel Size (mm)';
            app.PixelSizemmEditField = uieditfield(app.Panel, 'numeric');
            app.PixelSizemmEditField.Value = 0.006;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end