classdef lab3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        AddImagesButton           matlab.ui.control.Button
        DetectCircleCenterButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DetectCircleCenterButton
        function DetectCircleCenterButtonPushed(app, event)
           i = length(app.AddImagesButton.imageQueue)
                for i >= 0 
                    %imshow(img)
                    % use Hough Transform to find circles
                    [centerPoint, radii] = imfindcircles(app.AddImagesButton.imageQueue[i],[100 200]);
                    %center = centerPoint(1:10,:) for if we were finding multiple
                    %circles in the same image
                    %radius = radii(1:10) for if we were finding multiple
                    %circles in the same image
                    viscircles(centerPoint, radii)
                    i = i - 1;
                end
        end

        % Button pushed function: AddImagesButton
        function AddImagesButtonPushed(app, event)
            % bring in image
            img = imread(uigetfile('.png''.jpg'));
            % add to array of images 
            imageQueue = [imageQueue, img];

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DetectCircleCenterButton = uibutton(app.UIFigure, 'push');
            app.DetectCircleCenterButton.ButtonPushedFcn = createCallbackFcn(app, @DetectCircleCenterButtonPushed, true);
            app.DetectCircleCenterButton.Text = 'Detect Circle Center';
            app.AddImagesButton = uibutton(app.UIFigure, 'push');
            app.AddImagesButton.ButtonPushedFcn = createCallbackFcn(app, @AddImagesButtonPushed, true);
            app.AddImagesButton.Text = 'Add Images';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end