classdef Lab_4_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup3                      matlab.ui.container.TabGroup
        FrameSettingsTab               matlab.ui.container.Tab
        PixelSizemmEditField           matlab.ui.control.NumericEditField
        PixelSizemmEditField_2Label    matlab.ui.control.Label
        BaselinemmEditField            matlab.ui.control.NumericEditField
        BaselinemmEditField_2Label     matlab.ui.control.Label
        FocalLengthmmEditField         matlab.ui.control.NumericEditField
        FocalLengthmmEditField_2Label  matlab.ui.control.Label
        ShiftEditField                 matlab.ui.control.NumericEditField
        ShiftEditField_2Label          matlab.ui.control.Label
        yNumPixelsEditField            matlab.ui.control.NumericEditField
        yNumPixelsEditField_2Label     matlab.ui.control.Label
        xNumPixelsEditField            matlab.ui.control.NumericEditField
        xNumPixelsEditField_2Label     matlab.ui.control.Label
        BallSettingsTab                matlab.ui.container.Tab
        UpperRadiusBoundEditField      matlab.ui.control.NumericEditField
        UpperRadiusBoundEditField_2Label  matlab.ui.control.Label
        LowerRadiusBoundEditField      matlab.ui.control.NumericEditField
        LowerRadiusBoundEditField_2Label  matlab.ui.control.Label
        CamDepthSettingsTab            matlab.ui.container.Tab
        CamDepth3EditField             matlab.ui.control.NumericEditField
        CamDepth3EditField_2Label      matlab.ui.control.Label
        CamDepth2EditField             matlab.ui.control.NumericEditField
        CamDepth2EditField_2Label      matlab.ui.control.Label
        CamDepth1EditField             matlab.ui.control.NumericEditField
        CamDepth1EditField_2Label      matlab.ui.control.Label
        TabGroup2                      matlab.ui.container.TabGroup
        img1Tab                        matlab.ui.container.Tab
        Calc_DepthEditField            matlab.ui.control.NumericEditField
        Calc_DepthEditFieldLabel       matlab.ui.control.Label
        RadiusEditField                matlab.ui.control.NumericEditField
        RadiusEditField_2Label         matlab.ui.control.Label
        CentroidXEditField             matlab.ui.control.NumericEditField
        CentroidXEditField_2Label      matlab.ui.control.Label
        CentroidYEditField             matlab.ui.control.NumericEditField
        CentroidYEditField_2Label      matlab.ui.control.Label
        img2Tab                        matlab.ui.container.Tab
        Calc_DepthEditField_2          matlab.ui.control.NumericEditField
        Calc_DepthEditField_2Label     matlab.ui.control.Label
        CentroidXEditField_2           matlab.ui.control.NumericEditField
        CentroidXEditField_2Label_2    matlab.ui.control.Label
        CentroidYEditField_2           matlab.ui.control.NumericEditField
        CentroidYEditField_2Label_2    matlab.ui.control.Label
        RadiusEditField_2              matlab.ui.control.NumericEditField
        RadiusEditField_2Label_2       matlab.ui.control.Label
        img3Tab                        matlab.ui.container.Tab
        Calc_DepthEditField_3          matlab.ui.control.NumericEditField
        Calc_DepthEditField_3Label     matlab.ui.control.Label
        CentroidXEditField_3           matlab.ui.control.NumericEditField
        CentroidXEditField_3Label      matlab.ui.control.Label
        CentroidYEditField_3           matlab.ui.control.NumericEditField
        CentroidYEditField_3Label      matlab.ui.control.Label
        RadiusEditField_3              matlab.ui.control.NumericEditField
        RadiusEditField_3Label         matlab.ui.control.Label
        img4Tab                        matlab.ui.container.Tab
        Calc_DepthEditField_4          matlab.ui.control.NumericEditField
        Calc_DepthEditField_4Label     matlab.ui.control.Label
        CentroidXEditField_4           matlab.ui.control.NumericEditField
        CentroidXEditField_4Label      matlab.ui.control.Label
        CentroidYEditField_4           matlab.ui.control.NumericEditField
        CentroidYEditField_4Label      matlab.ui.control.Label
        RadiusEditField_4              matlab.ui.control.NumericEditField
        RadiusEditField_4Label         matlab.ui.control.Label
        img5Tab                        matlab.ui.container.Tab
        Calc_DepthEditField_5          matlab.ui.control.NumericEditField
        Calc_DepthEditField_5Label     matlab.ui.control.Label
        CentroidXEditField_5           matlab.ui.control.NumericEditField
        CentroidXEditField_5Label      matlab.ui.control.Label
        CentroidYEditField_5           matlab.ui.control.NumericEditField
        CentroidYEditField_5Label      matlab.ui.control.Label
        RadiusEditField_5              matlab.ui.control.NumericEditField
        RadiusEditField_5Label         matlab.ui.control.Label
        img6Tab                        matlab.ui.container.Tab
        Calc_DepthEditField_6          matlab.ui.control.NumericEditField
        Calc_DepthEditField_6Label     matlab.ui.control.Label
        CentroidXEditField_6           matlab.ui.control.NumericEditField
        CentroidXEditField_6Label      matlab.ui.control.Label
        CentroidYEditField_6           matlab.ui.control.NumericEditField
        CentroidYEditField_6Label      matlab.ui.control.Label
        RadiusEditField_6              matlab.ui.control.NumericEditField
        RadiusEditField_6Label         matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        CamXTab                        matlab.ui.container.Tab
        BallXCentroidRightEditField    matlab.ui.control.NumericEditField
        BallXCentroidRightEditFieldLabel  matlab.ui.control.Label
        BallXCentroidLeftEditField     matlab.ui.control.NumericEditField
        BallXCentroidLeftEditFieldLabel  matlab.ui.control.Label
        RightEditField                 matlab.ui.control.NumericEditField
        RightEditFieldLabel            matlab.ui.control.Label
        LeftEditField                  matlab.ui.control.NumericEditField
        LeftEditFieldLabel             matlab.ui.control.Label
        CamYTab                        matlab.ui.container.Tab
        BallYCentroidRightEditField    matlab.ui.control.NumericEditField
        BallYCentroidRightEditFieldLabel  matlab.ui.control.Label
        BallYCentroidLeftEditField     matlab.ui.control.NumericEditField
        BallYCentroidLeftEditFieldLabel  matlab.ui.control.Label
        RightEditField_2               matlab.ui.control.NumericEditField
        RightEditField_2Label          matlab.ui.control.Label
        LeftEditField_2                matlab.ui.control.NumericEditField
        LeftEditField_2Label           matlab.ui.control.Label
        DispDepthTab                   matlab.ui.container.Tab
        YpositionmmEditField           matlab.ui.control.NumericEditField
        YpositionmmEditFieldLabel      matlab.ui.control.Label
        XpositionmmEditField           matlab.ui.control.NumericEditField
        XpositionmmEditFieldLabel      matlab.ui.control.Label
        depthmmEditField               matlab.ui.control.NumericEditField
        depthmmEditFieldLabel          matlab.ui.control.Label
        disparitymmEditField           matlab.ui.control.NumericEditField
        disparitymmEditFieldLabel      matlab.ui.control.Label
        FindCircleButton               matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: FindCircleButton
        function FindCircleButtonPushed(app, event)

%Include source files in path
addpath(genpath('../src'))

%Initialization Parameters
server_ip   = '127.0.0.1';     %IP address of the Unity Server
server_port = 55001;           %Server Port of the Unity Sever

client = tcpclient(server_ip,server_port);
fprintf(1,"Connected to server\n");

               % cxLeft - static
               app.LeftEditField.Value = (app.xNumPixelsEditField.Value)/2
               % cxRight - static
               app.RightEditField.Value = (app.xNumPixelsEditField.Value)/2 
               % cyLeft - static
               app.LeftEditField_2.Value = (app.yNumPixelsEditField.Value)/2
               % cyRight - static
               app.RightEditField_2.Value = (app.yNumPixelsEditField.Value)/2 

               colormap gray


            depth = app.CamDepth1EditField.Value
           
            % x(in out),y(left right),z(up down),yaw[z],pitch[y],roll[x]
            %%% 1
            pose = [depth,-0.03,0,0,0,0];
            unityImageLeft = unityLink(client,pose);
            %figure out why the image is squished
            subplot(3,4,1)
            imshow(unityImageLeft)
            
            Frame_1 = unityImageLeft;
            formated_frame_1 = rgb2gray(Frame_1);
            colormap gray
            imagesc(formated_frame_1)
            
            subplot(3,4,3)
            imshow(formated_frame_1)
            [center, radius] = imfindcircles(formated_frame_1,[app.LowerRadiusBoundEditField.Value app.UpperRadiusBoundEditField.Value],"Sensitivity",0.70)
            hold on
            viscircles(center, radius, 'Color', 'red')
            scatter(center(1), center(2))
            
            %%% 2
            pose = [depth,0.03,0,0,0,0];
            unityImageRight = unityLink(client,pose);
            %
            subplot(3,4,2)
            imshow(unityImageRight);
            
            Frame_2 = unityImageRight;
            formated_frame_2 = rgb2gray(Frame_2);
            colormap gray
            imagesc(formated_frame_2)
            
            subplot(3,4,4)
            imshow(formated_frame_2)
            [center2, radius2] = imfindcircles(formated_frame_2,[app.LowerRadiusBoundEditField.Value app.UpperRadiusBoundEditField.Value],"Sensitivity",0.70)
            hold on
            viscircles(center2, radius2, 'Color', 'red')
            scatter(center2(1), center2(2))
      
            %hold on
            app.CentroidXEditField.Value = center(1)
            app.CentroidYEditField.Value = center(2)
            app.RadiusEditField.Value = radius

            app.CentroidXEditField_2.Value = center2(1)
            app.CentroidYEditField_2.Value = center2(2)
            app.RadiusEditField_2.Value = radius2
            
               % depth in millimeters
               %app.Calc_DepthEditField.Value = (app.BaselinemmEditField.Value*app.FocalLengthmmEditField.Value)/(abs((app.CentroidXEditField.Value-app.LeftEditField.Value)-(app.CentroidYEditField.Value-app.RightEditField.Value))*app.PixelSizemmEditField.Value)
               app.Calc_DepthEditField_2.Value = (app.BaselinemmEditField.Value*app.FocalLengthmmEditField.Value)/(abs((app.CentroidXEditField.Value-app.LeftEditField.Value)-(app.CentroidXEditField_2.Value-app.RightEditField.Value))*app.PixelSizemmEditField.Value)


            depth = app.CamDepth2EditField.Value
            %%% 3
            pose = [depth,-0.03,0,0,0,0];
            unityImageLeft = unityLink(client,pose);
            %figure out why the image is squished
            subplot(3,4,5)
            imshow(unityImageLeft)
            
            Frame_1 = unityImageLeft;
            formated_frame_1 = rgb2gray(Frame_1);
            colormap gray
            imagesc(formated_frame_1)
            
            subplot(3,4,7)
            imshow(formated_frame_1)
            [center, radius] = imfindcircles(formated_frame_1,[app.LowerRadiusBoundEditField.Value app.UpperRadiusBoundEditField.Value],"Sensitivity",0.70)
            hold on
            viscircles(center, radius, 'Color', 'red')
            scatter(center(1), center(2))
            
            %%% 4
            pose = [depth,0.03,0,0,0,0];
            unityImageRight = unityLink(client,pose);
            
            subplot(3,4,6)
            imshow(unityImageRight);
            
            Frame_2 = unityImageRight;
            formated_frame_2 = rgb2gray(Frame_2);
            colormap gray
            imagesc(formated_frame_2)
            
            subplot(3,4,8)
            imshow(formated_frame_2)
            [center2, radius2] = imfindcircles(formated_frame_2,[app.LowerRadiusBoundEditField.Value app.UpperRadiusBoundEditField.Value],"Sensitivity",0.70)
            hold on
            viscircles(center2, radius2, 'Color', 'red')
            scatter(center2(1), center2(2))
      
            %hold on
            app.CentroidXEditField_3.Value = center(1)
            app.CentroidYEditField_3.Value = center(2)
            app.RadiusEditField_3.Value = radius

            app.CentroidXEditField_4.Value = center2(1)
            app.CentroidYEditField_4.Value = center2(2)
            app.RadiusEditField_4.Value = radius2

               % depth in millimeters
               %app.Calc_DepthEditField_3.Value = (app.BaselinemmEditField.Value*app.FocalLengthmmEditField.Value)/(abs((app.CentroidXEditField_3.Value-app.LeftEditField.Value)-(app.CentroidYEditField_3.Value-app.RightEditField.Value))*app.PixelSizemmEditField.Value)
               app.Calc_DepthEditField_4.Value = (app.BaselinemmEditField.Value*app.FocalLengthmmEditField.Value)/(abs((app.CentroidXEditField_3.Value-app.LeftEditField.Value)-(app.CentroidXEditField_4.Value-app.RightEditField.Value))*app.PixelSizemmEditField.Value)


            depth = app.CamDepth3EditField.Value
            %%% 5
            pose = [depth,-0.03,0,0,0,0];
            unityImageLeft = unityLink(client,pose);
            %figure out why the image is squished
            subplot(3,4,1)
            imshow(unityImageLeft)
            
            Frame_1 = unityImageLeft;
            formated_frame_1 = rgb2gray(Frame_1);
            colormap gray
            imagesc(formated_frame_1)
            
            subplot(3,4,3)
            imshow(formated_frame_1)
            [center, radius] = imfindcircles(formated_frame_1,[app.LowerRadiusBoundEditField.Value app.UpperRadiusBoundEditField.Value],"Sensitivity",0.70)
            hold on
            viscircles(center, radius, 'Color', 'red')
            scatter(center(1), center(2))
            %%% 6
            pose = [depth,0.03,0,0,0,0];
            unityImageRight = unityLink(client,pose);
            %
            subplot(3,4,2)
            imshow(unityImageRight);
            
            Frame_2 = unityImageRight;
            formated_frame_2 = rgb2gray(Frame_2);
            colormap gray
            imagesc(formated_frame_2)
            
            subplot(3,4,4)
            imshow(formated_frame_2)
            [center2, radius2] = imfindcircles(formated_frame_2,[app.LowerRadiusBoundEditField.Value app.UpperRadiusBoundEditField.Value],"Sensitivity",0.70)
            hold on
            viscircles(center2, radius2, 'Color', 'red')
            scatter(center2(1), center2(2))
      
            %hold on
            app.CentroidXEditField_5.Value = center(1)
            app.CentroidYEditField_5.Value = center(2)
            app.RadiusEditField_5.Value = radius

            app.CentroidXEditField_6.Value = center2(1)
            app.CentroidYEditField_6.Value = center2(2)
            app.RadiusEditField_6.Value = radius2


               % depth in millimeters
               %app.Calc_DepthEditField_5.Value = (app.BaselinemmEditField.Value*app.FocalLengthmmEditField.Value)/(abs((app.CentroidXEditField_5.Value-app.LeftEditField.Value)-(app.CentroidYEditField_5.Value-app.RightEditField.Value))*app.PixelSizemmEditField.Value)
               app.Calc_DepthEditField_6.Value = (app.BaselinemmEditField.Value*app.FocalLengthmmEditField.Value)/(abs((app.CentroidXEditField_5.Value-app.LeftEditField.Value)-(app.CentroidXEditField_6.Value-app.RightEditField.Value))*app.PixelSizemmEditField.Value)
        
            actual_depth = [app.CamDepth1EditField.Value app.CamDepth2EditField.Value app.CamDepth3EditField.Value]
            calculated_depth = [app.Calc_DepthEditField_2.Value app.Calc_DepthEditField_4.Value app.Calc_DepthEditField_6.Value]  
            error = calculated_depth-actual_depth
            x = 0:1:4
            figure
            plot(x,actual_depth,x,error)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FindCircleButton = uibutton(app.UIFigure, 'push');
            app.FindCircleButton.ButtonPushedFcn = createCallbackFcn(app, @FindCircleButtonPushed, true);
            app.FindCircleButton.Text = 'Find Circle';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CamXTab = uitab(app.TabGroup);
            app.CamXTab.Title = 'Cam X ';
            app.LeftEditFieldLabel = uilabel(app.CamXTab);
            app.LeftEditFieldLabel.Text = 'Left';
            app.LeftEditField = uieditfield(app.CamXTab, 'numeric');
            app.RightEditFieldLabel = uilabel(app.CamXTab);
            app.RightEditFieldLabel.Text = 'Right';
            app.RightEditField = uieditfield(app.CamXTab, 'numeric');
            app.BallXCentroidLeftEditFieldLabel = uilabel(app.CamXTab);
            app.BallXCentroidLeftEditFieldLabel.Text = 'Ball X Centroid Left';
            app.BallXCentroidLeftEditField = uieditfield(app.CamXTab, 'numeric');
            app.BallXCentroidRightEditFieldLabel = uilabel(app.CamXTab);
            app.BallXCentroidRightEditFieldLabel.Text = 'Ball X Centroid Right';
            app.BallXCentroidRightEditField = uieditfield(app.CamXTab, 'numeric');
            app.CamYTab = uitab(app.TabGroup);
            app.CamYTab.Title = 'Cam Y';
            app.LeftEditField_2Label = uilabel(app.CamYTab);
            app.LeftEditField_2Label.Text = 'Left';
            app.LeftEditField_2 = uieditfield(app.CamYTab, 'numeric');
            app.RightEditField_2Label = uilabel(app.CamYTab);
            app.RightEditField_2Label.Text = 'Right';
            app.RightEditField_2 = uieditfield(app.CamYTab, 'numeric');
            app.BallYCentroidLeftEditFieldLabel = uilabel(app.CamYTab);
            app.BallYCentroidLeftEditFieldLabel.Text = 'Ball Y Centroid Left';
            app.BallYCentroidLeftEditField = uieditfield(app.CamYTab, 'numeric');
            app.BallYCentroidRightEditFieldLabel = uilabel(app.CamYTab);
            app.BallYCentroidRightEditFieldLabel.Text = 'Ball Y Centroid Right';
            app.BallYCentroidRightEditField = uieditfield(app.CamYTab, 'numeric');
            app.DispDepthTab = uitab(app.TabGroup);
            app.DispDepthTab.Title = 'Disp/Depth';
            app.disparitymmEditFieldLabel = uilabel(app.DispDepthTab);
            app.disparitymmEditFieldLabel.Text = 'disparity (mm)';
            app.disparitymmEditField = uieditfield(app.DispDepthTab, 'numeric');
            app.depthmmEditFieldLabel = uilabel(app.DispDepthTab);
            app.depthmmEditFieldLabel.Text = 'depth (mm)';
            app.depthmmEditField = uieditfield(app.DispDepthTab, 'numeric');
            app.XpositionmmEditFieldLabel = uilabel(app.DispDepthTab);
            app.XpositionmmEditFieldLabel.Text = 'X position (mm)';
            app.XpositionmmEditField = uieditfield(app.DispDepthTab, 'numeric');
            app.YpositionmmEditFieldLabel = uilabel(app.DispDepthTab);
            app.YpositionmmEditFieldLabel.Text = 'Y position (mm)';
            app.YpositionmmEditField = uieditfield(app.DispDepthTab, 'numeric');
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.img1Tab = uitab(app.TabGroup2);
            app.img1Tab.Title = 'img1';
            app.CentroidYEditField_2Label = uilabel(app.img1Tab);
            app.CentroidYEditField_2Label.Text = 'Centroid Y';
            app.CentroidYEditField = uieditfield(app.img1Tab, 'numeric');
            app.CentroidXEditField_2Label = uilabel(app.img1Tab);
            app.CentroidXEditField_2Label.Text = 'Centroid X';
            app.CentroidXEditField = uieditfield(app.img1Tab, 'numeric');
            app.RadiusEditField_2Label = uilabel(app.img1Tab);
            app.RadiusEditField_2Label.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.img1Tab, 'numeric');
            app.Calc_DepthEditFieldLabel = uilabel(app.img1Tab);
            app.Calc_DepthEditFieldLabel.Text = 'Calc_Depth';
            app.Calc_DepthEditField = uieditfield(app.img1Tab, 'numeric');
            app.img2Tab = uitab(app.TabGroup2);
            app.img2Tab.Title = 'img2';
            app.RadiusEditField_2Label_2 = uilabel(app.img2Tab);
            app.RadiusEditField_2Label_2.Text = 'Radius';
            app.RadiusEditField_2 = uieditfield(app.img2Tab, 'numeric');
            app.CentroidYEditField_2Label_2 = uilabel(app.img2Tab);
            app.CentroidYEditField_2Label_2.Text = 'Centroid Y';
            app.CentroidYEditField_2 = uieditfield(app.img2Tab, 'numeric');
            app.CentroidXEditField_2Label_2 = uilabel(app.img2Tab);
            app.CentroidXEditField_2Label_2.Text = 'Centroid X';
            app.CentroidXEditField_2 = uieditfield(app.img2Tab, 'numeric');
            app.Calc_DepthEditField_2Label = uilabel(app.img2Tab);
            app.Calc_DepthEditField_2Label.Text = 'Calc_Depth';
            app.Calc_DepthEditField_2 = uieditfield(app.img2Tab, 'numeric');
            app.img3Tab = uitab(app.TabGroup2);
            app.img3Tab.Title = 'img3';
            app.RadiusEditField_3Label = uilabel(app.img3Tab);
            app.RadiusEditField_3Label.Text = 'Radius';
            app.RadiusEditField_3 = uieditfield(app.img3Tab, 'numeric');
            app.CentroidYEditField_3Label = uilabel(app.img3Tab);
            app.CentroidYEditField_3Label.Text = 'Centroid Y';
            app.CentroidYEditField_3 = uieditfield(app.img3Tab, 'numeric');
            app.CentroidXEditField_3Label = uilabel(app.img3Tab);
            app.CentroidXEditField_3Label.Text = 'Centroid X';
            app.CentroidXEditField_3 = uieditfield(app.img3Tab, 'numeric');
            app.Calc_DepthEditField_3Label = uilabel(app.img3Tab);
            app.Calc_DepthEditField_3Label.Text = 'Calc_Depth';
            app.Calc_DepthEditField_3 = uieditfield(app.img3Tab, 'numeric');
            app.img4Tab = uitab(app.TabGroup2);
            app.img4Tab.Title = 'img4';
            app.RadiusEditField_4Label = uilabel(app.img4Tab);
            app.RadiusEditField_4Label.Text = 'Radius';
            app.RadiusEditField_4 = uieditfield(app.img4Tab, 'numeric');
            app.CentroidYEditField_4Label = uilabel(app.img4Tab);
            app.CentroidYEditField_4Label.Text = 'Centroid Y';
            app.CentroidYEditField_4 = uieditfield(app.img4Tab, 'numeric');
            app.CentroidXEditField_4Label = uilabel(app.img4Tab);
            app.CentroidXEditField_4Label.Text = 'Centroid X';
            app.CentroidXEditField_4 = uieditfield(app.img4Tab, 'numeric');
            app.Calc_DepthEditField_4Label = uilabel(app.img4Tab);
            app.Calc_DepthEditField_4Label.Text = 'Calc_Depth';
            app.Calc_DepthEditField_4 = uieditfield(app.img4Tab, 'numeric');
            app.img5Tab = uitab(app.TabGroup2);
            app.img5Tab.Title = 'img5';
            app.RadiusEditField_5Label = uilabel(app.img5Tab);
            app.RadiusEditField_5Label.Text = 'Radius';
            app.RadiusEditField_5 = uieditfield(app.img5Tab, 'numeric');
            app.CentroidYEditField_5Label = uilabel(app.img5Tab);
            app.CentroidYEditField_5Label.Text = 'Centroid Y';
            app.CentroidYEditField_5 = uieditfield(app.img5Tab, 'numeric');
            app.CentroidXEditField_5Label = uilabel(app.img5Tab);
            app.CentroidXEditField_5Label.Text = 'Centroid X';
            app.CentroidXEditField_5 = uieditfield(app.img5Tab, 'numeric');
            app.Calc_DepthEditField_5Label = uilabel(app.img5Tab);
            app.Calc_DepthEditField_5Label.Text = 'Calc_Depth';
            app.Calc_DepthEditField_5 = uieditfield(app.img5Tab, 'numeric');
            app.img6Tab = uitab(app.TabGroup2);
            app.img6Tab.Title = 'img6';
            app.RadiusEditField_6Label = uilabel(app.img6Tab);
            app.RadiusEditField_6Label.Text = 'Radius';
            app.RadiusEditField_6 = uieditfield(app.img6Tab, 'numeric');
            app.CentroidYEditField_6Label = uilabel(app.img6Tab);
            app.CentroidYEditField_6Label.Text = 'Centroid Y';
            app.CentroidYEditField_6 = uieditfield(app.img6Tab, 'numeric');
            app.CentroidXEditField_6Label = uilabel(app.img6Tab);
            app.CentroidXEditField_6Label.Text = 'Centroid X';
            app.CentroidXEditField_6 = uieditfield(app.img6Tab, 'numeric');
            app.Calc_DepthEditField_6Label = uilabel(app.img6Tab);
            app.Calc_DepthEditField_6Label.Text = 'Calc_Depth';
            app.Calc_DepthEditField_6 = uieditfield(app.img6Tab, 'numeric');
            app.TabGroup3 = uitabgroup(app.UIFigure);
            app.FrameSettingsTab = uitab(app.TabGroup3);
            app.FrameSettingsTab.Title = 'Frame Settings';
            app.xNumPixelsEditField_2Label = uilabel(app.FrameSettingsTab);
            app.xNumPixelsEditField_2Label.Text = 'xNumPixels';
            app.xNumPixelsEditField = uieditfield(app.FrameSettingsTab, 'numeric');
            app.xNumPixelsEditField.Value = 752;
            app.yNumPixelsEditField_2Label = uilabel(app.FrameSettingsTab);
            app.yNumPixelsEditField_2Label.Text = 'yNumPixels';
            app.yNumPixelsEditField = uieditfield(app.FrameSettingsTab, 'numeric');
            app.yNumPixelsEditField.Value = 480;
            app.ShiftEditField_2Label = uilabel(app.FrameSettingsTab);
            app.ShiftEditField_2Label.Text = 'Shift';
            app.ShiftEditField = uieditfield(app.FrameSettingsTab, 'numeric');
            app.ShiftEditField.Value = 10;
            app.FocalLengthmmEditField_2Label = uilabel(app.FrameSettingsTab);
            app.FocalLengthmmEditField_2Label.Text = 'Focal Length (mm)';
            app.FocalLengthmmEditField = uieditfield(app.FrameSettingsTab, 'numeric');
            app.FocalLengthmmEditField.Value = 6;
            app.BaselinemmEditField_2Label = uilabel(app.FrameSettingsTab);
            app.BaselinemmEditField_2Label.Text = 'Baseline (mm)';
            app.BaselinemmEditField = uieditfield(app.FrameSettingsTab, 'numeric');
            app.BaselinemmEditField.Value = 60;
            app.PixelSizemmEditField_2Label = uilabel(app.FrameSettingsTab);
            app.PixelSizemmEditField_2Label.Text = 'Pixel Size (mm)';
            app.PixelSizemmEditField = uieditfield(app.FrameSettingsTab, 'numeric');
            app.PixelSizemmEditField.Value = 0.006;
            app.BallSettingsTab = uitab(app.TabGroup3);
            app.BallSettingsTab.Title = 'Ball Settings';
            app.LowerRadiusBoundEditField_2Label = uilabel(app.BallSettingsTab);
            app.LowerRadiusBoundEditField_2Label.Text = 'Lower Radius Bound';
            app.LowerRadiusBoundEditField = uieditfield(app.BallSettingsTab, 'numeric');
            app.LowerRadiusBoundEditField.Value = 2;
            app.UpperRadiusBoundEditField_2Label = uilabel(app.BallSettingsTab);
            app.UpperRadiusBoundEditField_2Label.Text = 'Upper Radius Bound';
            app.UpperRadiusBoundEditField = uieditfield(app.BallSettingsTab, 'numeric');
            app.UpperRadiusBoundEditField.Value = 25;
            app.CamDepthSettingsTab = uitab(app.TabGroup3);
            app.CamDepthSettingsTab.Title = 'Cam Depth Settings';
            app.CamDepth1EditField_2Label = uilabel(app.CamDepthSettingsTab);
            app.CamDepth1EditField_2Label.Text = 'Cam Depth 1';
            app.CamDepth1EditField = uieditfield(app.CamDepthSettingsTab, 'numeric');
            app.CamDepth1EditField.Value = -0.5;
            app.CamDepth2EditField_2Label = uilabel(app.CamDepthSettingsTab);
            app.CamDepth2EditField_2Label.Text = 'Cam Depth 2';
            app.CamDepth2EditField = uieditfield(app.CamDepthSettingsTab, 'numeric');
            app.CamDepth2EditField.Value = -1.25;
            app.CamDepth3EditField_2Label = uilabel(app.CamDepthSettingsTab);
            app.CamDepth3EditField_2Label.Text = 'Cam Depth 3';
            app.CamDepth3EditField = uieditfield(app.CamDepthSettingsTab, 'numeric');
            app.CamDepth3EditField.Value = -3;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lab_4_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end